classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UITable                   matlab.ui.control.Table
        UITable2                  matlab.ui.control.Table
        Panel                     matlab.ui.container.Panel
        newfiltersEditFieldLabel  matlab.ui.control.Label
        newfiltersEditField       matlab.ui.control.EditField
        Button                    matlab.ui.control.Button
        MyNewsLabel               matlab.ui.control.Label
        Button_2                  matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        Label_2                   matlab.ui.control.Label
        DropDown_2                matlab.ui.control.DropDown
    end


    properties (Access = private)
        count_num % Description
        show_num % Description
        filters % Description
        current_dir % Description
    end


    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %state=system('python D:\\我的文档\\文档\\大学\\code_pycharm\\main.py');
            current_dir1=pwd;
            current_dir1=strcat(current_dir1,"\\");
            app.current_dir=current_dir1;
            filename = strcat(current_dir1,"main.py");
            command = strcat("python ",filename);
            state=system(command);
            %若state==0则运行正确
            if isempty(app.count_num)
                collect_num=2;
            else
                collect_num=app.count_num;
            end
            if isempty(app.show_num)
                show_num1=10;
            else
                show_num1=app.show_num;
            end
            txtname=select_data(collect_num);
            result=analysis(txtname,show_num1);
            %result=read_data('D:\\我的文档\\文档\\大学\\code_pycharm\\微博.txt');
            nameArray='Data';
            oldData=[];
            newData=oldData;
            
            %for i = 1:size(result{1,1},1)
            for i=1:show_num1
                newData=[newData;{result{1,1}(i,:),result{1,2}(i,:),result{1,3}(i,:)}];
                set(app.UITable,nameArray,newData);   
            end
            if exist('filter.txt','file')
                show_filters(app);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if(value=='2')
                app.count_num=2;
            elseif(value=='4')
                app.count_num=4;
            else
                app.count_num=10;
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if(value=='10')
                app.show_num=10;
            elseif(value=='25')
                app.show_num=25;
            else
                app.show_num=50;
            end

        end

        % Value changed function: newfiltersEditField
        function newfiltersEditFieldValueChanged(app, event)
            value = app.newfiltersEditField.Value;
            app.filters=value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %添加filters
            if(isempty(app.filters))
                %错误处理
            else
                word=app.filters;
                file= 'filter.txt';
                if exist(file,'file')
                    f=fopen(file,'a','n','utf-8');
                    fprintf(f,"%s",strcat(word," "));
                    fclose(f);
                else
                    f=fopen(file,'w','n','utf-8');
                    fprintf(f,"%s",strcat(word," "));
                    fclose(f);
                end
                show_filters(app);
                %mycommand = strcat(strcat("python D:\\我的文档\\文档\\大学\\code_pycharm\\main.py "),word);
                %state=system(mycommand);
                %if state==0
                    %运行正确
                    %show_filters(app);
                %else
                    %运行错误
                %end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; 'Things'; 'Number'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'filters'};
            app.UITable2.RowName = {};
            app.Panel = uipanel(app.UIFigure);
            app.newfiltersEditFieldLabel = uilabel(app.Panel);
            app.newfiltersEditFieldLabel.Text = 'new filters';
            app.newfiltersEditField = uieditfield(app.Panel, 'text');
            app.newfiltersEditField.ValueChangedFcn = createCallbackFcn(app, @newfiltersEditFieldValueChanged, true);
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定';
            app.MyNewsLabel = uilabel(app.UIFigure);
            app.MyNewsLabel.Text = 'My News';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '更新';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '来源前';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'2', '4', '全部'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '2';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '选取前';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'10', '25', '50'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '10';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end